classdef covidCR_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TimeEditFieldLabel             matlab.ui.control.Label
        TimeEditField                  matlab.ui.control.NumericEditField
        InitialEEditFieldLabel         matlab.ui.control.Label
        InitialEEditField              matlab.ui.control.NumericEditField
        minmaxContactC1EditFieldLabel  matlab.ui.control.Label
        minContactC1EditField          matlab.ui.control.NumericEditField
        maxContactC1EditField          matlab.ui.control.NumericEditField
        maxContactC2EditField          matlab.ui.control.NumericEditField
        minmaxContactC2Label           matlab.ui.control.Label
        minContactC2EditField          matlab.ui.control.NumericEditField
        maxContactC3EditField          matlab.ui.control.NumericEditField
        maxContactC4EditField          matlab.ui.control.NumericEditField
        minmaxContactC3Label           matlab.ui.control.Label
        minContactC3EditField          matlab.ui.control.NumericEditField
        minmaxContactC4Label           matlab.ui.control.Label
        minContactC4EditField          matlab.ui.control.NumericEditField
        C1EditFieldLabel               matlab.ui.control.Label
        C11EditField                   matlab.ui.control.NumericEditField
        C12EditField                   matlab.ui.control.NumericEditField
        C13EditField                   matlab.ui.control.NumericEditField
        C14EditField                   matlab.ui.control.NumericEditField
        C22EditField                   matlab.ui.control.NumericEditField
        C23EditField                   matlab.ui.control.NumericEditField
        C24EditField                   matlab.ui.control.NumericEditField
        C2Label                        matlab.ui.control.Label
        C21EditField                   matlab.ui.control.NumericEditField
        C32EditField                   matlab.ui.control.NumericEditField
        C33EditField                   matlab.ui.control.NumericEditField
        C34EditField                   matlab.ui.control.NumericEditField
        C42EditField                   matlab.ui.control.NumericEditField
        C43EditField                   matlab.ui.control.NumericEditField
        C44EditField                   matlab.ui.control.NumericEditField
        C3Label                        matlab.ui.control.Label
        C31EditField                   matlab.ui.control.NumericEditField
        C4Label                        matlab.ui.control.Label
        C41EditField                   matlab.ui.control.NumericEditField
        C1Label                        matlab.ui.control.Label
        C2Label_2                      matlab.ui.control.Label
        C3Label_2                      matlab.ui.control.Label
        C4Label_2                      matlab.ui.control.Label
        minmaxLayer2Label              matlab.ui.control.Label
        minLayer2EditField             matlab.ui.control.NumericEditField
        maxLayer2EditField             matlab.ui.control.NumericEditField
        maxLayer3EditField             matlab.ui.control.NumericEditField
        minmaxLayer3Label              matlab.ui.control.Label
        minLayer3EditField             matlab.ui.control.NumericEditField
        OisolatedLabel                 matlab.ui.control.Label
        IsolatedSlider                 matlab.ui.control.Slider
        p1EditFieldLabel               matlab.ui.control.Label
        p1EditField                    matlab.ui.control.NumericEditField
        p2Label                        matlab.ui.control.Label
        p2EditField                    matlab.ui.control.NumericEditField
        p3Label                        matlab.ui.control.Label
        p3EditField                    matlab.ui.control.NumericEditField
        p4Label                        matlab.ui.control.Label
        p4EditField                    matlab.ui.control.NumericEditField
        MaskLabel                      matlab.ui.control.Label
        MaskSlider                     matlab.ui.control.Slider
        DistancingSliderLabel          matlab.ui.control.Label
        DistancingSlider               matlab.ui.control.Slider
        C1EditFieldLabel_2             matlab.ui.control.Label
        C1HouseEditField               matlab.ui.control.NumericEditField
        C2EditFieldLabel               matlab.ui.control.Label
        C2HouseEditField               matlab.ui.control.NumericEditField
        C3EditFieldLabel               matlab.ui.control.Label
        C3HouseEditField               matlab.ui.control.NumericEditField
        C4EditFieldLabel               matlab.ui.control.Label
        C4HouseEditField               matlab.ui.control.NumericEditField
        C1PopEditField                 matlab.ui.control.NumericEditField
        C2PopEditField                 matlab.ui.control.NumericEditField
        C3PopEditField                 matlab.ui.control.NumericEditField
        C4PopEditField                 matlab.ui.control.NumericEditField
        C1A1PopEditField               matlab.ui.control.NumericEditField
        C1A2PopEditField               matlab.ui.control.NumericEditField
        C2A1PopEditField               matlab.ui.control.NumericEditField
        C2A2PopEditField               matlab.ui.control.NumericEditField
        C3A1PopEditField               matlab.ui.control.NumericEditField
        C3A2PopEditField               matlab.ui.control.NumericEditField
        C4A1PopEditField               matlab.ui.control.NumericEditField
        C4A2PopEditField               matlab.ui.control.NumericEditField
        PopLabel                       matlab.ui.control.Label
        NFLabel                        matlab.ui.control.Label
        AG1Label                       matlab.ui.control.Label
        AG2Label                       matlab.ui.control.Label
        PlotresultsforDropDownLabel    matlab.ui.control.Label
        PlotresultsforDropDown         matlab.ui.control.DropDown
        SCheckBox                      matlab.ui.control.CheckBox
        ECheckBox                      matlab.ui.control.CheckBox
        OCheckBox                      matlab.ui.control.CheckBox
        UCheckBox                      matlab.ui.control.CheckBox
        HCheckBox                      matlab.ui.control.CheckBox
        RCheckBox                      matlab.ui.control.CheckBox
        DCheckBox                      matlab.ui.control.CheckBox
        O1CheckBox                     matlab.ui.control.CheckBox
        O2CheckBox                     matlab.ui.control.CheckBox
        O3CheckBox                     matlab.ui.control.CheckBox
        H1CheckBox                     matlab.ui.control.CheckBox
        H2CheckBox                     matlab.ui.control.CheckBox
        H3CheckBox                     matlab.ui.control.CheckBox
        RunButton                      matlab.ui.control.Button
        totalcasesCheckBox             matlab.ui.control.CheckBox
        Image                          matlab.ui.control.Image
        ClearButton                    matlab.ui.control.Button
        ShowpreviousresultsButton      matlab.ui.control.StateButton
        FamilyinfoLabel                matlab.ui.control.Label
        ConnectivityLabel              matlab.ui.control.Label
        CopyrightC2021EpiMECLabel      matlab.ui.control.Label
        C1A3PopEditField               matlab.ui.control.NumericEditField
        C2A3PopEditField               matlab.ui.control.NumericEditField
        C3A3PopEditField               matlab.ui.control.NumericEditField
        C4A3PopEditField               matlab.ui.control.NumericEditField
        AG3Label                       matlab.ui.control.Label
        LoadtransitionsbetweenstatesButton  matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        N = 0; % population
        familyData   = nan(4,4);
        connectivity = nan(4,4);
        mimMaxBubble = nan(4,2);
        totalS   = []; totalE   = []; totalR   = [];
        totalO1  = []; totalO2  = []; totalO3  = [];
        totalU1  = []; totalU2  = []; totalU3  = [];
        totalH1  = []; totalH2  = []; totalH3  = [];
        totalD1  = []; totalD2  = []; totalD3  = [];
        accumD1  = []; accumD2  = []; accumD3  = [];
        ototalS  = []; ototalE  = []; ototalR  = [];
        ototalO1 = []; ototalO2 = []; ototalO3 = [];
        ototalU1 = []; ototalU2 = []; ototalU3 = [];
        ototalH1 = []; ototalH2 = []; ototalH3 = [];
        ototalD1 = []; ototalD2 = []; ototalD3 = [];
        oaccumD1 = []; oaccumD2 = []; oaccumD3 = [];
        % data for counties
        dCkO;
        dCkU;
        dCkH;
        dCkAcum;
        dCkDead;
        % data for counties from previous simulation
        odCkO;
        odCkU;
        odCkH;
        odCkAcum;
        odCkDead;
        oTime = []; % time of previous simulation
        File = 'prob.xls';
        acum;
    end
    
    methods (Access = private)
        function read_and_save_data(app)
            app.familyData(1,1) = app.C1HouseEditField.Value;
            app.familyData(1,2) = app.C1PopEditField.Value;
            app.familyData(1,3) = app.C1A1PopEditField.Value;
            app.familyData(1,4) = app.C1A2PopEditField.Value;
            app.familyData(2,1) = app.C2HouseEditField.Value;
            app.familyData(2,2) = app.C2PopEditField.Value;
            app.familyData(2,3) = app.C2A1PopEditField.Value;
            app.familyData(2,4) = app.C2A2PopEditField.Value;
            app.familyData(3,1) = app.C3HouseEditField.Value;
            app.familyData(3,2) = app.C3PopEditField.Value;
            app.familyData(3,3) = app.C3A1PopEditField.Value;
            app.familyData(3,4) = app.C3A2PopEditField.Value;
            app.familyData(4,1) = app.C4HouseEditField.Value;
            app.familyData(4,2) = app.C4PopEditField.Value;
            app.familyData(4,3) = app.C4A1PopEditField.Value;
            app.familyData(4,4) = app.C4A2PopEditField.Value;
            app.connectivity(1,1) = app.C11EditField.Value;
            app.connectivity(1,2) = app.C12EditField.Value;
            app.connectivity(1,3) = app.C13EditField.Value;
            app.connectivity(1,4) = app.C14EditField.Value;
            app.connectivity(2,1) = app.C21EditField.Value;
            app.connectivity(2,2) = app.C22EditField.Value;
            app.connectivity(2,3) = app.C23EditField.Value;
            app.connectivity(2,4) = app.C24EditField.Value;
            app.connectivity(3,1) = app.C31EditField.Value;
            app.connectivity(3,2) = app.C32EditField.Value;
            app.connectivity(3,3) = app.C33EditField.Value;
            app.connectivity(3,4) = app.C34EditField.Value;
            app.connectivity(4,1) = app.C41EditField.Value;
            app.connectivity(4,2) = app.C42EditField.Value;
            app.connectivity(4,3) = app.C43EditField.Value;
            app.connectivity(4,4) = app.C44EditField.Value;
            app.minContactC1EditField.Value = min(app.minContactC1EditField.Value,app.maxContactC1EditField.Value);
            app.minContactC2EditField.Value = min(app.minContactC2EditField.Value,app.maxContactC2EditField.Value);
            app.minContactC3EditField.Value = min(app.minContactC3EditField.Value,app.maxContactC3EditField.Value);
            app.minContactC4EditField.Value = min(app.minContactC4EditField.Value,app.maxContactC4EditField.Value);
            app.mimMaxBubble(1,1) = app.minContactC1EditField.Value;
            app.mimMaxBubble(2,1) = app.minContactC2EditField.Value;
            app.mimMaxBubble(3,1) = app.minContactC3EditField.Value;
            app.mimMaxBubble(4,1) = app.minContactC4EditField.Value;
            app.mimMaxBubble(1,2) = app.maxContactC1EditField.Value;
            app.mimMaxBubble(2,2) = app.maxContactC2EditField.Value;
            app.mimMaxBubble(3,2) = app.maxContactC3EditField.Value;
            app.mimMaxBubble(4,2) = app.maxContactC4EditField.Value;
            app.C1A3PopEditField.Value = max(0,1-app.C1A1PopEditField.Value-app.C1A2PopEditField.Value);
            app.C2A3PopEditField.Value = max(0,1-app.C2A1PopEditField.Value-app.C2A2PopEditField.Value);
            app.C3A3PopEditField.Value = max(0,1-app.C3A1PopEditField.Value-app.C3A2PopEditField.Value);
            app.C4A3PopEditField.Value = max(0,1-app.C4A1PopEditField.Value-app.C4A2PopEditField.Value);
            app.minLayer2EditField.Value = min(app.minLayer2EditField.Value,app.maxLayer2EditField.Value);
            app.minLayer3EditField.Value = min(app.minLayer3EditField.Value,app.maxLayer3EditField.Value);
        end
        
        function model(app)
            numCounties = 4;
            coefSpor = .5;
            coefFam = 1;
            coefMin = 0.5;%0.012;
            %acum = readProbFile(app,'prob');
            % estados: %0-S 1-E 2-O 3-U 4-H 5-R 6-D
            probE    = xlsread(app.File,'E');   probE    = probE(:,2:end);
            probD1   = xlsread(app.File,'OE1'); probD1   = probD1(:,2:end);
            probD2   = xlsread(app.File,'OE2'); probD2   = probD2(:,2:end);
            probD3   = xlsread(app.File,'OE3'); probD3   = probD3(:,2:end);
            probH1E1 = xlsread(app.File,'HE1'); probH1E1 = probH1E1(:,2:end);
            probH1E2 = xlsread(app.File,'HE2'); probH1E2 = probH1E2(:,2:end);
            probH1E3 = xlsread(app.File,'HE3'); probH1E3 = probH1E3(:,2:end);
            probU    = xlsread(app.File,'U');   probU    = probU(:,2:end);
            % estados: %0-S 1-E 2-O 3-U 4-H 5-R 6-D
            app.acum.E   = min([zeros(size(probE,1),1), cumsum(probE,2)],1);
            app.acum.D1  = min([zeros(size(probD1,1),1), cumsum(probD1,2)],1);
            app.acum.D2  = min([zeros(size(probD2,1),1), cumsum(probD2,2)],1);
            app.acum.D3  = min([zeros(size(probD3,1),1), cumsum(probD3,2)],1);
            app.acum.HE1 = min([zeros(size(probH1E1,1),1), cumsum(probH1E1,2)],1);
            app.acum.HE2 = min([zeros(size(probH1E2,1),1), cumsum(probH1E2,2)],1);
            app.acum.HE3 = min([zeros(size(probH1E3,1),1), cumsum(probH1E3,2)],1);
            app.acum.U   = min([zeros(size(probU,1),1), cumsum(probU,2)],1);
            
            % parameters
            T = app.TimeEditField.Value;
            min_coworkers   = app.minLayer2EditField.Value;
            delta_coworkers = app.maxLayer2EditField.Value-min_coworkers;
            min_sporadic    = app.minLayer3EditField.Value;
            delta_sporadic  = app.maxLayer3EditField.Value-min_sporadic;
            pUseMask = app.MaskSlider.Value;
            nearCounties = app.connectivity;
            minmaxBubble = app.mimMaxBubble;
            numInitExposed = app.InitialEEditField.Value;
            
            p   = app.p1EditField.Value;
            pD  = app.p2EditField.Value;
            pM  = app.p3EditField.Value;
            pDM = app.p4EditField.Value;
            pO  = 1-app.IsolatedSlider.Value;
            minDist  = app.DistancingSlider.Value;%10;
            
            if(~isempty(app.accumD1)) % store previous results
                app.oaccumD1 = app.accumD1;
                app.oaccumD2 = app.accumD2;
                app.oaccumD3 = app.accumD3;
                app.ototalS  = app.totalS;
                app.ototalE  = app.totalE;
                app.ototalR  = app.totalR;
                app.ototalU1 = app.totalU1;
                app.ototalU2 = app.totalU2;
                app.ototalU3 = app.totalU3;
                app.ototalO1 = app.totalO1;
                app.ototalO2 = app.totalO2;
                app.ototalO3 = app.totalO3;
                app.ototalD1 = app.totalD1;
                app.ototalD2 = app.totalD2;
                app.ototalD3 = app.totalD3;
                app.ototalH1 = app.totalH1;
                app.ototalH2 = app.totalH2;
                app.ototalH3 = app.totalH3;
                app.odCkO    = app.dCkO;
                app.odCkAcum = app.dCkAcum; 
                app.odCkH    = app.dCkH;
                app.odCkDead = app.dCkDead;
                app.odCkU    = app.dCkU;
            end
            
            % Data.
            % daily data (S, E, R: totals; O, U, H, D: per age group)
            totS  = zeros(1,T,'uint32'); acumD1 = zeros(1,T+1,'uint32');
            totE  = zeros(1,T,'uint32'); acumD2 = zeros(1,T+1,'uint32');
            totR  = zeros(1,T,'uint32'); acumD3 = zeros(1,T+1,'uint32');
            totO1 = zeros(1,T,'uint32'); totU1 = zeros(1,T,'uint32');
            totH1 = zeros(1,T,'uint32'); totD1 = zeros(1,T,'uint32');
            totO2 = zeros(1,T,'uint32'); totU2 = zeros(1,T,'uint32');
            totH2 = zeros(1,T,'uint32'); totD2 = zeros(1,T,'uint32');
            totO3 = zeros(1,T,'uint32'); totU3 = zeros(1,T,'uint32');
            totH3 = zeros(1,T,'uint32'); totD3 = zeros(1,T,'uint32');
            % info por cantón
            dataCkO    = zeros(numCounties,T,'uint32');
            dataCkAcum = zeros(numCounties,T,'uint32');
            dataCkH    = zeros(numCounties,T,'uint32');
            dataCkDead = zeros(numCounties,T,'uint32');
            dataCkU    = zeros(numCounties,T,'uint32');
            % 2. Create household network
            %[numIDs,numFam,idToCounty,idToFamily,idToAge,familyToIDs,countyToIDs] = setupFamilies(app);
            %function [numIDs,numFam,idToCounty,idToFamily,idToAge,...
            %        familyToIDs,countyToIDs] = setupFamilies(app)
            % read demographic data (located in the same directory)
            data = app.familyData;
            houses_county  = data(:,1); age_group_01 = data(:,3);
            people_county  = data(:,2)'; age_group_02 = data(:,4);
            numCounties  = size(data,1);           % number of counties
            numFam       = sum(houses_county); % number of households
            % allocate arrays
            people_families = zeros(1,numFam,'uint8'); % people per family
            people_age      = cell(1,numCounties);     % age for ID per county
            % create families based on Poisson distribution
            index = 0;
            for j = 1:numCounties
                local_houses     = houses_county(j);
                local_population = people_county(j);
                avrg_per_house   = local_population/local_houses;
                % create distribution for number of individuals per family
                number_people_per_house = poissrnd(avrg_per_house-1,local_houses,1)+1;
                % store number of people per family
                adv = numel(number_people_per_house);
                people_families(index+1:index+adv) = number_people_per_house;
                index = index + adv;
                % correct number of people per county (due to the random distribution)
                people_county(j) = sum(number_people_per_house);
                % include age groups
                n1 = floor(age_group_01(j)*people_county(j));
                n2 = floor(age_group_02(j)*people_county(j));
                n3 = people_county(j)-n1-n2;
                % create number of people per age labeled as 1, 2, 3
                age_groups = [ones(1,n1), 2*ones(1,n2), 3*ones(1,n3)];
                % sort age groups
                people_age{j} = age_groups(randperm(people_county(j)));
            end
            % accumulate sums for families and counties
            cumsum_people_per_family = [0 cumsum(uint32(people_families))];
            cumsum_people_per_county = [0 cumsum(people_county)];
            numIDs = cumsum_people_per_county(end); % total population
            % allocate arrays
            idToAge     = zeros(1,cumsum_people_per_family(end),'uint8');
            idToCounty  = zeros(1,cumsum_people_per_county(end),'uint8');
            countyToIDs = cell(1,numCounties);
            idToFamily  = zeros(1,cumsum_people_per_family(end),'uint32');
            familyToIDs = cell(1,numFam);
            % fill idToCounty, idToAge, countyToIDs
            index = 0;
            for j = 1:numCounties
                idToCounty(cumsum_people_per_county(j)+1:cumsum_people_per_county(j+1)) = j;
                countyToIDs{j} = cumsum_people_per_county(j)+1:cumsum_people_per_county(j+1);
                adv = numel(people_age{j});
                idToAge(index+1:index+adv) = people_age{j};
                index = index + adv;
            end
            % fill idToFamily, familyToIDs
            for j = 1:numFam
                idToFamily(cumsum_people_per_family(j)+1:cumsum_people_per_family(j+1)) = j;
                familyToIDs{j} = uint32(cumsum_people_per_family(j)+1:cumsum_people_per_family(j+1));
            end
            %end
            app.N = numIDs;
            % keep track of...
            familyAdded = false(numFam,1); % ... added families
            notadded = true(numIDs,1);     % ... not added IDs
            % edge connectivity per layer
            edges = cell(numIDs,3);
            % number of contacts per ID: uniform value on [min, min+delta-1]
            dgrLyr2 = uint8(min_coworkers+randi(delta_coworkers+1,numIDs,1)-1);
            dgrLyr3 = uint8(min_sporadic +randi(delta_sporadic+1, numIDs,1)-1);
            degrees = [dgrLyr2 dgrLyr3];
            % list of possible contacts (to save time)
            poolPeople   = cell(numCounties,1); % lista de elegibles por cantón
            for k = 1:numCounties
                poolPeople{k} = uint32(cat(2,countyToIDs{nearCounties(k,:)>0}));
            end
            % 3. Initial conditions
            % states: 0-S 1-E 2-O 3-U 4-H 5-R 6-D
            stateID     = zeros(numIDs,1,'uint8');
            timeStateID = zeros(numIDs,1,'uint8');
            % numInitExposed initial exposed IDs
            newExposedIDs = randi(numIDs,[numInitExposed,1]);
            stateID(newExposedIDs) = 1; % S->E
            timeStateID(newExposedIDs) = 1; % on day 1
            % 4.
            for day = 1:T
                % update network: include contacts for new exposed people
                for j = 1:numel(newExposedIDs)
                    currID     = newExposedIDs(j);  % current ID
                    numFamily  = idToFamily(currID);
                    if(~familyAdded(numFamily)) % check if family has been added before
                        familyNodes = familyToIDs{numFamily};  % IDs for family
                        nodesToAdd = familyNodes(notadded(familyNodes));
                        notadded(nodesToAdd) = false;          % update added nodes
                        allEdges = combnk(familyNodes,2);      % family edges
                        familyAdded(numFamily)= true;          % mark family as added
                        for m = 1:size(allEdges,1)             % update edges
                            edges{allEdges(m,1),1}(end+1) = allEdges(m,2);
                            edges{allEdges(m,2),1}(end+1) = allEdges(m,1);
                        end
                    end
                    layer = 2;%:3
                    newConnections = max(0,degrees(currID,layer-1)-numel(edges{currID,layer}));
                    if(newConnections>0)
                        % who is eligible
                        population = poolPeople{idToCounty(currID)};
                        elegiblesW = population(randi(numel(population),1,3*newConnections));
                        elegiblesW = setdiff(elegiblesW, [currID edges{currID,1} edges{currID,2} edges{currID,3}]);
                        newCoworkers = elegiblesW(randperm(numel(elegiblesW),newConnections));
                        % find preexisting nodes on the graph
                        nodesToAdd = newCoworkers(notadded(newCoworkers));
                        % update added
                        notadded(nodesToAdd) = false;
                        % add edges
                        allEdges = newCoworkers;
                        % update neighborhs
                        edges{currID,layer}(end+1:end+numel(allEdges)) = allEdges;
                        for m = 1:numel(allEdges)
                            edges{allEdges(m),layer}(end+1) = currID;
                        end
                    end
                end
                layer = 3;
                for j = 1:numIDs % delete layer 3
                    edges{j,layer} = [];
                end
                % include layer 3 for infectious nodes
                for currID = find(stateID==2 | stateID==3)' 
                    newConnections = degrees(currID,layer-1);
                    population = poolPeople{idToCounty(currID)};
                    elegiblesW = population(randi(numel(population),1,2*newConnections));
                    elegiblesW = setdiff(elegiblesW,[currID edges{currID,1} edges{currID,2} edges{currID,3}]);
                    newCoworkers = elegiblesW(randperm(numel(elegiblesW),min(numel(elegiblesW),newConnections)));
                    % find preexisting nodes on the graph
                    nodesToAdd = newCoworkers(notadded(newCoworkers));
                    % update added
                    notadded(nodesToAdd) = false;
                    % add edges
                    allEdges = newCoworkers;
                    % update neighborhs
                    edges{currID,layer} = allEdges;
                    for m = 1:numel(allEdges)
                        edges{allEdges(m),layer}(end+1) = currID;
                    end
                end
                % probability of infection: state 2, 3, 4 can infect their contacts
                newExposedIDs = [];   % restart list of new E
                StoE = unique(cat(2,edges{(stateID==2|stateID==3|stateID==4),1:3}));
                useMask  = randsample(2,numIDs,true,[1-pUseMask pUseMask]);
                pUseDist = minDist*ones(numCounties,1);%(minDist+(100-minDist)*rand(numCounties,1))/100;
                for currID = StoE
                    if(stateID(currID)==0)  % check susceptibles
                        numCounty  = idToCounty(currID);
                        sizeBubble = minmaxBubble(numCounty,1)+randi(minmaxBubble(numCounty,2)-minmaxBubble(numCounty,1)+1,1)-1;
                        chosenN1 = edges{currID,2};
                        chosenN2 = edges{currID,3};
                        if(numel(chosenN1)+numel(chosenN2) > sizeBubble)
                            nN = randi(sizeBubble+1,1)-1;
                            nN1 = min(nN,length(chosenN1));
                            nN2 = min(sizeBubble-nN1,length(chosenN2));
                            while(nN1+nN2<sizeBubble)
                                if(nN1<length(chosenN1))
                                    nN1 = nN1 + 1;
                                end
                                if(nN2<length(chosenN2))
                                    nN2 = nN2 + 1;
                                end
                            end
                            chosenN1 = chosenN1(randsample(length(chosenN1),nN1));
                            chosenN2 = chosenN2(randsample(length(chosenN2),nN2));
                        end
                        % distribution for people that use mask and apply social distancing
                        useMask1 = useMask(chosenN1);
                        useMask2 = useMask(chosenN2);
                        [~, useDist1] = histc(rand(numel(chosenN1),1),[0 1-pUseDist(numCounty) 1]);
                        [~, useDist2] = histc(rand(numel(chosenN2),1),[0 1-pUseDist(numCounty) 1]);
                        % 0-S 1-E 2-O 3-U 4-H 5-R 6-D
                        states = stateID(edges{currID,1});             % layer 1
                        iu = states == 3;
                        id = states == 2 | states == 4;
                        p1 = sum(iu) + pO*sum(id);
                        risk = (1-coefFam*p).^(coefMin*p1);
                        states = stateID(chosenN1);                    % layer 2
                        iu = states == 3;
                        id = states == 2 | states == 4;
                        for k = 1:numel(iu)
                            if(iu(k)==1)
                                if(useMask1(k)==2 && useDist1(k)==2)
                                    risk = risk*((1-pDM).^coefMin);
                                elseif(useMask1(k)==2 && useDist1(k)==1)
                                    risk = risk*((1-pM).^coefMin);
                                elseif(useMask1(k)==1 && useDist1(k)==2)
                                    risk = risk*((1-pD).^coefMin);
                                else
                                    risk = risk*((1-p).^coefMin);
                                end
                            end
                        end
                        for k = 1:numel(id)
                            if(id(k)==1)
                                if(useMask1(k)==2 && useDist1(k)==2)
                                    risk = risk*((1-pDM).^(coefMin*pO));
                                elseif(useMask1(k)==2 && useDist1(k)==1)
                                    risk = risk*((1-pM).^(coefMin*pO));
                                elseif(useMask1(k)==1 && useDist1(k)==2)
                                    risk = risk*((1-pD).^(coefMin*pO));
                                else
                                    risk = risk*((1-p).^(coefMin*pO));
                                end
                            end
                        end
                        states = stateID(chosenN2);                    % layer 3
                        iu = states == 3;
                        id = states == 2 | states == 4;
                        for k = 1:numel(iu)
                            if(iu(k)==1)
                                if(useMask2(k)==2 && useDist2(k)==2)
                                    risk = risk*((1-coefSpor*pDM).^coefMin);
                                elseif(useMask2(k)==2 && useDist2(k)==1)
                                    risk = risk*((1-coefSpor*pM).^coefMin);
                                elseif(useMask2(k)==1 && useDist2(k)==2)
                                    risk = risk*((1-coefSpor*pD).^coefMin);
                                else
                                    risk = risk*((1-coefSpor*p).^coefMin);
                                end
                            end
                        end
                        for k = 1:numel(id)
                            if(id(k)==1)
                                if(useMask2(k)==2 && useDist2(k)==2)
                                    risk = risk*((1-coefSpor*pDM).^(coefMin*pO));
                                elseif(useMask2(k)==2 && useDist2(k)==1)
                                    risk = risk*((1-coefSpor*pM).^(coefMin*pO));
                                elseif(useMask2(k)==1 && useDist2(k)==2)
                                    risk = risk*((1-coefSpor*pD).^(coefMin*pO));
                                else
                                    risk = risk*((1-coefSpor*p).^(coefMin*pO));
                                end
                            end
                        end
                        risk = 1 - risk; % probability infection
                        [~, infected] = histc(rand,[0 risk 1]);
                        if(infected==1)
                            newExposedIDs = [newExposedIDs, currID]; %#ok<*AGROW>
                        end
                    end
                end
                % transitions
                timeStateID(newExposedIDs) = day; % first mark S->E
                stateID(newExposedIDs) = 1;
                elegible = find(stateID>0 & stateID<5); % all transitions but S->E
                states = stateID(elegible);             % all states
                indexD = [];                            % store new cases for accum data
                for j = 1:numel(states)
                    state = states(j);
                    ID = elegible(j);
                    switch state
                        case 1 % E
                            time = day - timeStateID(ID);
                            [~, event] = histc(rand,app.acum.E(time+1,:)); %#ok<*HISTC>
                            if(event~=1)
                                stateID(ID) = event;
                                timeStateID(ID) = day;
                            end
                            if(event==2) % observed case
                                indexD = [indexD; ID];
                            end
                        case 2 % D
                            time = day - timeStateID(ID);
                            age  = idToAge(ID);
                            switch age
                                case 1
                                    [~, event] = histc(rand,app.acum.D1(time+1,:));
                                case 2
                                    [~, event] = histc(rand,app.acum.D2(time+1,:));
                                case 3
                                    [~, event] = histc(rand,app.acum.D3(time+1,:));
                            end
                            if(event~=2)
                                stateID(ID) = event;
                                timeStateID(ID) = day;
                            end
                        case 3 % U
                            time = day - timeStateID(ID);
                            [~, event] = histc(rand,app.acum.U(time+1,:));
                            if(event~=3)
                                stateID(ID) = event;
                                timeStateID(ID) = day;
                            end
                        case 4 % H
                            time = day - timeStateID(ID);
                            age  = idToAge(ID);
                            switch age
                                case 1
                                    [~, event] = histc(rand,app.acum.HE1(time+1,:));
                                case 2
                                    [~, event] = histc(rand,app.acum.HE2(time+1,:));
                                case 3
                                    [~, event] = histc(rand,app.acum.HE3(time+1,:));
                            end
                            if(event~=4)
                                stateID(ID) = event;
                                timeStateID(ID) = day;
                            end
                    end
                end
                % save data
                is = (stateID==0);  ie = (stateID==1);  io = (stateID==2)';
                iu = (stateID==3)'; ih = (stateID==4)'; ir = (stateID==5);
                id = (stateID==6)';
                acumD1(day+1)   = acumD1(day) + sum(idToAge(indexD)==1);
                acumD2(day+1)   = acumD2(day) + sum(idToAge(indexD)==2);
                acumD3(day+1)   = acumD3(day) + sum(idToAge(indexD)==3);
                totS(day) = sum(is);  totE(day) = sum(ie);  totR(day) = sum(ir);
                % info according age groups
                ii = (idToAge==1);
                totU1(day) = sum(iu & ii); totO1(day) = sum(io & ii);
                totH1(day) = sum(ih & ii); totD1(day) = sum(id & ii);
                ii = (idToAge==2);
                totU2(day) = sum(iu & ii); totO2(day) = sum(io & ii);
                totH2(day) = sum(ih & ii); totD2(day) = sum(id & ii);
                ii = (idToAge==3);
                totU3(day) = sum(iu & ii); totO3(day) = sum(io & ii);
                totH3(day) = sum(ih & ii); totD3(day) = sum(id & ii);
                % save info per county
                acumCounty          = hist(idToCounty(indexD),1:numCounties)';
                dataCkO(:,day+1)    = hist(idToCounty(io),1:numCounties); %#ok<*HIST,*UNRCH>
                dataCkAcum(:,day+1) = dataCkAcum(:,day)+uint32(acumCounty);
                dataCkH(:,day+1)    = hist(idToCounty(ih),1:numCounties);
                dataCkDead(:,day+1) = hist(idToCounty(id),1:numCounties);
                dataCkU(:,day+1)    = hist(idToCounty(iu),1:numCounties);
                %day
            end
            app.accumD1 = acumD1;
            app.accumD2 = acumD2;
            app.accumD3 = acumD3;
            app.totalS = totS;
            app.totalE = totE;
            app.totalR = totR;
            app.totalU1 = totU1;
            app.totalU2 = totU2;
            app.totalU3 = totU3;
            app.totalO1 = totO1;
            app.totalO2 = totO2;
            app.totalO3 = totO3;
            app.totalD1 = totD1;
            app.totalD2 = totD2;
            app.totalD3 = totD3;
            app.totalH1 = totH1;
            app.totalH2 = totH2;
            app.totalH3 = totH3;
            app.dCkO    = dataCkO;
            app.dCkAcum = dataCkAcum;
            app.dCkH    = dataCkH;
            app.dCkDead = dataCkDead;
            app.dCkU    = dataCkU;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            read_and_save_data(app);
        end

        % Value changed function: C11EditField, C12EditField, 
        % C13EditField, C14EditField, C1A1PopEditField, 
        % C1A2PopEditField, C1HouseEditField, C1PopEditField, 
        % C21EditField, C22EditField, C23EditField, C24EditField, 
        % C2A1PopEditField, C2A2PopEditField, C2HouseEditField, 
        % C2PopEditField, C31EditField, C32EditField, C33EditField, 
        % C34EditField, C3A1PopEditField, C3A2PopEditField, 
        % C3HouseEditField, C3PopEditField, C41EditField, 
        % C42EditField, C43EditField, C44EditField, 
        % C4A1PopEditField, C4A2PopEditField, C4HouseEditField, 
        % C4PopEditField, maxContactC1EditField, 
        % maxContactC2EditField, maxContactC3EditField, 
        % maxContactC4EditField, maxLayer2EditField, 
        % maxLayer3EditField, minContactC1EditField, 
        % minContactC2EditField, minContactC3EditField, 
        % minContactC4EditField, minLayer2EditField, 
        % minLayer3EditField
        function updateData(app, event)
            read_and_save_data(app);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            d = uiprogressdlg(app.UIFigure,'Title','Running model',...
                'Indeterminate','on');
            drawnow
            model(app);
            % close the dialog box
            close(d)
            plotData(app);
        end

        % Value changed function: DCheckBox, ECheckBox, H1CheckBox, 
        % H2CheckBox, H3CheckBox, HCheckBox, O1CheckBox, 
        % O2CheckBox, O3CheckBox, OCheckBox, 
        % PlotresultsforDropDown, RCheckBox, SCheckBox, 
        % ShowpreviousresultsButton, UCheckBox, totalcasesCheckBox
        function plotData(app, event)
            if(strcmp(app.PlotresultsforDropDown.Value,'Country'))
                cla(app.UIAxes)
                tem = 0;
                xlimax = 0;
                if(~isempty(app.totalS))
                    timePlot = numel(app.totalS);
                    if(app.SCheckBox.Value)
                        temData = double(app.totalS)/app.N*100;
                        hold(app.UIAxes,'on');
                        plot(app.UIAxes,1:timePlot,temData,'b');
                        tem = tem+1;
                        legend1{tem} = 'S';
                        xlimax = max(xlimax,max(temData));
                        hold(app.UIAxes,'off');
                    end
                    if(app.ECheckBox.Value)
                        temData = double(app.totalE)/app.N*100;
                        hold(app.UIAxes,'on');
                        plot(app.UIAxes,1:timePlot,temData,'g');
                        tem = tem+1;
                        legend1{tem} = 'E';
                        xlimax = max(xlimax,max(temData));
                        hold(app.UIAxes,'off');
                    end
                    if(app.OCheckBox.Value)
                        temData = double(app.totalO1+app.totalO2+app.totalO3)/app.N*100;
                        hold(app.UIAxes,'on');
                        plot(app.UIAxes,1:timePlot,temData,'r');
                        tem = tem+1;
                        legend1{tem} = 'O';
                        xlimax = max(xlimax,max(temData));
                        hold(app.UIAxes,'off');
                    end
                    if(app.UCheckBox.Value)
                        temData = double(app.totalU1+app.totalU2+app.totalU3)/app.N*100;
                        hold(app.UIAxes,'on');
                        plot(app.UIAxes,1:timePlot,temData,'k');
                        tem = tem+1;
                        legend1{tem} = 'U';
                        xlimax = max(xlimax,max(temData));
                        hold(app.UIAxes,'off');
                    end
                    if(app.HCheckBox.Value)
                        temData = double(app.totalH1+app.totalH2+app.totalH3)/app.N*100;
                        hold(app.UIAxes,'on');
                        plot(app.UIAxes,1:timePlot,temData,'c');
                        tem = tem+1;
                        legend1{tem} = 'H';
                        xlimax = max(xlimax,max(temData));
                        hold(app.UIAxes,'off');
                    end
                    if(app.RCheckBox.Value)
                        temData = double(app.totalR)/app.N*100;
                        hold(app.UIAxes,'on');
                        plot(app.UIAxes,1:timePlot,temData,'m');
                        tem = tem+1;
                        legend1{tem} = 'U';
                        xlimax = max(xlimax,max(temData));
                        hold(app.UIAxes,'off');
                    end
                    if(app.DCheckBox.Value)
                        temData = double(app.totalD1+app.totalD2+app.totalD3)/app.N*100;
                        hold(app.UIAxes,'on');
                        plot(app.UIAxes,1:timePlot,temData,'Color',.5*[1 1 1]);
                        tem = tem+1;
                        legend1{tem} = 'D';
                        xlimax = max(xlimax,max(temData));
                        hold(app.UIAxes,'off');
                    end
                    if(app.H1CheckBox.Value)
                        temData = double(app.totalH1)/app.N*100;
                        hold(app.UIAxes,'on');
                        plot(app.UIAxes,1:timePlot,temData,'c.');
                        tem = tem+1;
                        legend1{tem} = 'H1';
                        xlimax = max(xlimax,max(temData));
                        hold(app.UIAxes,'off');
                    end
                    if(app.H2CheckBox.Value)
                        temData = double(app.totalH2)/app.N*100;
                        hold(app.UIAxes,'on');
                        plot(app.UIAxes,1:timePlot,temData,'cx');
                        tem = tem+1;
                        legend1{tem} = 'H2';
                        xlimax = max(xlimax,max(temData));
                        hold(app.UIAxes,'off');
                    end
                    if(app.H3CheckBox.Value)
                        temData = double(app.totalH3)/app.N*100;
                        hold(app.UIAxes,'on');
                        plot(app.UIAxes,1:timePlot,temData,'co');
                        tem = tem+1;
                        legend1{tem} = 'H3';
                        xlimax = max(xlimax,max(temData));
                        hold(app.UIAxes,'off');
                    end
                    if(app.O1CheckBox.Value)
                        temData = double(app.totalO1)/app.N*100;
                        hold(app.UIAxes,'on');
                        plot(app.UIAxes,1:timePlot,temData,'r.');
                        tem = tem+1;
                        legend1{tem} = 'O1';
                        xlimax = max(xlimax,max(temData));
                        hold(app.UIAxes,'off');
                    end
                    if(app.O2CheckBox.Value)
                        temData = double(app.totalO2)/app.N*100;
                        hold(app.UIAxes,'on');
                        plot(app.UIAxes,1:timePlot,temData,'rx');
                        tem = tem+1;
                        legend1{tem} = 'O2';
                        xlimax = max(xlimax,max(temData));
                        hold(app.UIAxes,'off');
                    end
                    if(app.O3CheckBox.Value)
                        temData = double(app.totalO3)/app.N*100;
                        hold(app.UIAxes,'on');
                        plot(app.UIAxes,1:timePlot,temData,'ro');
                        tem = tem+1;
                        legend1{tem} = 'O3';
                        xlimax = max(xlimax,max(temData));
                        hold(app.UIAxes,'off');
                    end
                    if(app.totalcasesCheckBox.Value)
                        temData = double(app.accumD1+app.accumD2+app.accumD3)/app.N*100;
                        hold(app.UIAxes,'on');
                        plot(app.UIAxes,1:1+timePlot,temData,'k');
                        tem = tem+1;
                        legend1{tem} = 'total';
                        xlimax = max(xlimax,max(temData));
                        hold(app.UIAxes,'off');
                    end
                end
                if(~isempty(app.ototalS) && app.ShowpreviousresultsButton.Value)
                    app.oTime = numel(app.ototalS);
                    if(app.SCheckBox.Value)
                        temData = double(app.ototalS)/app.N*100;
                        hold(app.UIAxes,'on');
                        plot(app.UIAxes,1:app.oTime,temData,'b--');
                        tem = tem+1;
                        legend1{tem} = 'S';
                        xlimax = max(xlimax,max(temData));
                        hold(app.UIAxes,'off');
                    end
                    if(app.ECheckBox.Value)
                        temData = double(app.ototalE)/app.N*100;
                        hold(app.UIAxes,'on');
                        plot(app.UIAxes,1:app.oTime,temData,'g--');
                        tem = tem+1;
                        legend1{tem} = 'E';
                        xlimax = max(xlimax,max(temData));
                        hold(app.UIAxes,'off');
                    end
                    if(app.OCheckBox.Value)
                        temData = double(app.ototalO1+app.ototalO2+app.ototalO3)/app.N*100;
                        hold(app.UIAxes,'on');
                        plot(app.UIAxes,1:app.oTime,temData,'r--');
                        tem = tem+1;
                        legend1{tem} = 'O';
                        xlimax = max(xlimax,max(temData));
                        hold(app.UIAxes,'off');
                    end
                    if(app.UCheckBox.Value)
                        temData = double(app.ototalU1+app.ototalU2+app.ototalU3)/app.N*100;
                        hold(app.UIAxes,'on');
                        plot(app.UIAxes,1:app.oTime,temData,'k--');
                        tem = tem+1;
                        legend1{tem} = 'U';
                        xlimax = max(xlimax,max(temData));
                        hold(app.UIAxes,'off');
                    end
                    if(app.HCheckBox.Value)
                        temData = double(app.ototalH1+app.ototalH2+app.ototalH3)/app.N*100;
                        hold(app.UIAxes,'on');
                        plot(app.UIAxes,1:app.oTime,temData,'c--');
                        tem = tem+1;
                        legend1{tem} = 'H';
                        xlimax = max(xlimax,max(temData));
                        hold(app.UIAxes,'off');
                    end
                    if(app.RCheckBox.Value)
                        temData = double(app.ototalR)/app.N*100;
                        hold(app.UIAxes,'on');
                        plot(app.UIAxes,1:app.oTime,temData,'m--');
                        tem = tem+1;
                        legend1{tem} = 'U';
                        xlimax = max(xlimax,max(temData));
                        hold(app.UIAxes,'off');
                    end
                    if(app.DCheckBox.Value)
                        temData = double(app.ototalD1+app.ototalD2+app.ototalD3)/app.N*100;
                        hold(app.UIAxes,'on');
                        plot(app.UIAxes,1:app.oTime,temData,'--','Color',.5*[1 1 1]);
                        tem = tem+1;
                        legend1{tem} = 'D';
                        xlimax = max(xlimax,max(temData));
                        hold(app.UIAxes,'off');
                    end
                    if(app.H1CheckBox.Value)
                        temData = double(app.ototalH1)/app.N*100;
                        hold(app.UIAxes,'on');
                        plot(app.UIAxes,1:app.oTime,temData,'c.--');
                        tem = tem+1;
                        legend1{tem} = 'H1';
                        xlimax = max(xlimax,max(temData));
                        hold(app.UIAxes,'off');
                    end
                    if(app.H2CheckBox.Value)
                        temData = double(app.ototalH2)/app.N*100;
                        hold(app.UIAxes,'on');
                        plot(app.UIAxes,1:app.oTime,temData,'cx--');
                        tem = tem+1;
                        legend1{tem} = 'H2';
                        xlimax = max(xlimax,max(temData));
                        hold(app.UIAxes,'off');
                    end
                    if(app.H3CheckBox.Value)
                        temData = double(app.ototalH3)/app.N*100;
                        hold(app.UIAxes,'on');
                        plot(app.UIAxes,1:app.oTime,temData,'co--');
                        tem = tem+1;
                        legend1{tem} = 'H3';
                        xlimax = max(xlimax,max(temData));
                        hold(app.UIAxes,'off');
                    end
                    if(app.O1CheckBox.Value)
                        temData = double(app.ototalO1)/app.N*100;
                        hold(app.UIAxes,'on');
                        plot(app.UIAxes,1:app.oTime,temData,'r.--');
                        tem = tem+1;
                        legend1{tem} = 'O1';
                        xlimax = max(xlimax,max(temData));
                        hold(app.UIAxes,'off');
                    end
                    if(app.O2CheckBox.Value)
                        temData = double(app.ototalO2)/app.N*100;
                        hold(app.UIAxes,'on');
                        plot(app.UIAxes,1:app.oTime,temData,'rx--');
                        tem = tem+1;
                        legend1{tem} = 'O2';
                        xlimax = max(xlimax,max(temData));
                        hold(app.UIAxes,'off');
                    end
                    if(app.O3CheckBox.Value)
                        temData = double(app.ototalO3)/app.N*100;
                        hold(app.UIAxes,'on');
                        plot(app.UIAxes,1:app.oTime,temData,'ro--');
                        tem = tem+1;
                        legend1{tem} = 'O3';
                        xlimax = max(xlimax,max(temData));
                        hold(app.UIAxes,'off');
                    end
                    if(app.totalcasesCheckBox.Value)
                        temData = double(app.oaccumD1+app.oaccumD2+app.oaccumD3)/app.N*100;
                        hold(app.UIAxes,'on');
                        plot(app.UIAxes,1:1+app.oTime,temData,'k--');
                        tem = tem+1;
                        legend1{tem} = 'total';
                        xlimax = max(xlimax,max(temData));
                        hold(app.UIAxes,'off');
                    end
                end
                if(tem>0)
                    legend(app.UIAxes,legend1);
                    app.UIAxes.XLim = [0 app.TimeEditField.Value];
                    if(xlimax>0)
                        app.UIAxes.YLim = [0 xlimax];
                    else
                        app.UIAxes.YLim = [0 1];
                    end
                end
            else % C1, C2, C3
                switch app.PlotresultsforDropDown.Value
                    case 'C1'
                        county = 1;
                    case 'C2'
                        county = 2;
                    case 'C3'
                        county = 3;
                    case 'C4'
                        county = 4;
                end
                %app.dCkO    = dataCkO;
                %app.dCkAcum = dataCkAcum;
                %app.dCkH    = dataCkH;
                %app.dCkDead = dataCkDead;
                %app.dCkU    = dataCkU;
                cla(app.UIAxes)
                tem = 0;
                xlimax = 0;
                if(~isempty(app.totalS))
                    if(app.OCheckBox.Value)
                        temData = double(app.dCkO(county,:))/app.N;
                        hold(app.UIAxes,'on');
                        plot(app.UIAxes,1:app.TimeEditField.Value,temData(2:end),'r');
                        tem = tem+1;
                        legend1{tem} = ['O - ' app.PlotresultsforDropDown.Value];
                        xlimax = max(xlimax,max(temData));
                        hold(app.UIAxes,'off');
                    end
                    if(app.UCheckBox.Value)
                        temData = double(app.dCkU(county,:))/app.N;
                        hold(app.UIAxes,'on');
                        plot(app.UIAxes,1:app.TimeEditField.Value,temData(2:end),'k');
                        tem = tem+1;
                        legend1{tem} = ['U - ' app.PlotresultsforDropDown.Value];
                        xlimax = max(xlimax,max(temData));
                        hold(app.UIAxes,'off');
                    end
                    if(app.HCheckBox.Value)
                        temData = double(app.dCkH(county,:))/app.N;
                        hold(app.UIAxes,'on');
                        plot(app.UIAxes,1:app.TimeEditField.Value,temData(2:end),'c');
                        tem = tem+1;
                        legend1{tem} = ['H - ' app.PlotresultsforDropDown.Value];
                        xlimax = max(xlimax,max(temData));
                        hold(app.UIAxes,'off');
                    end
                    if(app.DCheckBox.Value)
                        temData = double(app.dCkDead(county,:))/app.N;
                        hold(app.UIAxes,'on');
                        plot(app.UIAxes,1:app.TimeEditField.Value,temData(2:end),'Color',.5*[1 1 1]);
                        tem = tem+1;
                        legend1{tem} = ['D - ' app.PlotresultsforDropDown.Value];
                        xlimax = max(xlimax,max(temData));
                        hold(app.UIAxes,'off');
                    end
                    if(app.totalcasesCheckBox.Value)
                        temData = double(app.dCkAcum(county,:))/app.N;
                        hold(app.UIAxes,'on');
                        plot(app.UIAxes,1:app.TimeEditField.Value,temData(2:end),'k');
                        tem = tem+1;
                        legend1{tem} = ['total - ' app.PlotresultsforDropDown.Value];
                        xlimax = max(xlimax,max(temData));
                        hold(app.UIAxes,'off');
                    end
                end
                if(~isempty(app.ototalS) && app.ShowpreviousresultsButton.Value)
                    app.oTime = numel(app.ototalS);
                    if(app.OCheckBox.Value)
                        temData = double(app.odCkO(county,:))/app.N;
                        hold(app.UIAxes,'on');
                        plot(app.UIAxes,1:app.oTime,temData(2:end),'r--');
                        tem = tem+1;
                        legend1{tem} = ['O - ' app.PlotresultsforDropDown.Value];
                        xlimax = max(xlimax,max(temData));
                        hold(app.UIAxes,'off');
                    end
                    if(app.UCheckBox.Value)
                        temData = double(app.odCkU(county,:))/app.N;
                        hold(app.UIAxes,'on');
                        plot(app.UIAxes,1:app.oTime,temData(2:end),'k--');
                        tem = tem+1;
                        legend1{tem} = ['U - ' app.PlotresultsforDropDown.Value];
                        xlimax = max(xlimax,max(temData));
                        hold(app.UIAxes,'off');
                    end
                    if(app.HCheckBox.Value)
                        temData = double(app.odCkH(county,:))/app.N;
                        hold(app.UIAxes,'on');
                        plot(app.UIAxes,1:app.oTime,temData(2:end),'c--');
                        tem = tem+1;
                        legend1{tem} = ['H - ' app.PlotresultsforDropDown.Value];
                        xlimax = max(xlimax,max(temData));
                        hold(app.UIAxes,'off');
                    end
                    if(app.DCheckBox.Value)
                        temData = double(app.odCkDead(county,:))/app.N;
                        hold(app.UIAxes,'on');
                        plot(app.UIAxes,1:app.oTime,temData(2:end),'--','Color',.5*[1 1 1]);
                        tem = tem+1;
                        legend1{tem} = ['D - ' app.PlotresultsforDropDown.Value];
                        xlimax = max(xlimax,max(temData));
                        hold(app.UIAxes,'off');
                    end
                    if(app.totalcasesCheckBox.Value)
                        temData = double(app.odCkAcum(county,:))/app.N;
                        hold(app.UIAxes,'on');
                        plot(app.UIAxes,1:app.oTime,temData(2:end),'k--');
                        tem = tem+1;
                        legend1{tem} = ['total - ' app.PlotresultsforDropDown.Value];
                        xlimax = max(xlimax,max(temData));
                        hold(app.UIAxes,'off');
                    end
                end
                if(tem>0)
                    legend(app.UIAxes,legend1);
                    app.UIAxes.XLim = [0 app.TimeEditField.Value];
                    app.UIAxes.YLim = [0 xlimax];
                end
            end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.totalS = [];
            app.totalE = [];
            app.totalR = [];
            app.totalO1 = [];
            app.totalO2 = [];
            app.totalO3 = [];
            app.totalU1 = [];
            app.totalU2 = [];
            app.totalU3 = [];
            app.totalH1 = [];
            app.totalH2 = [];
            app.totalH3 = [];
            app.totalD1 = [];
            app.totalD2 = [];
            app.totalD3 = [];
            app.accumD1 = [];
            app.accumD2 = [];
            app.accumD3 = [];
            app.ototalS = [];
            app.ototalE = [];
            app.ototalR = [];
            app.ototalO1 = [];
            app.ototalO2 = [];
            app.ototalO3 = [];
            app.ototalU1 = [];
            app.ototalU2 = [];
            app.ototalU3 = [];
            app.ototalH1 = [];
            app.ototalH2 = [];
            app.ototalH3 = [];
            app.ototalD1 = [];
            app.ototalD2 = [];
            app.ototalD3 = [];
            app.oaccumD1 = [];
            app.oaccumD2 = [];
            app.oaccumD3 = [];
            cla(app.UIAxes)
        end

        % Button pushed function: LoadtransitionsbetweenstatesButton
        function LoadtransitionsbetweenstatesButtonPushed(app, event)
            %file = 'prob'; % READ FILE
            [file,path] = uigetfile('*.xls');
            if isequal(file,0)
                disp('User selected Cancel');
            else
                disp(['User selected ', fullfile(path,file)]);
            end
            app.File = [path file];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TimeEditFieldLabel = uilabel(app.UIFigure);
            app.TimeEditFieldLabel.Tooltip = {'Running time'};
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeEditField.Limits = [0 200];
            app.TimeEditField.RoundFractionalValues = 'on';
            app.TimeEditField.Tooltip = {''};
            app.TimeEditField.Value = 90;
            app.InitialEEditFieldLabel = uilabel(app.UIFigure);
            app.InitialEEditFieldLabel.Tooltip = {'Initial number of exposed nodes'};
            app.InitialEEditFieldLabel.Text = 'Initial E';
            app.InitialEEditField = uieditfield(app.UIFigure, 'numeric');
            app.InitialEEditField.Limits = [0 100];
            app.InitialEEditField.RoundFractionalValues = 'on';
            app.InitialEEditField.Tooltip = {''};
            app.InitialEEditField.Value = 10;
            app.minmaxContactC1EditFieldLabel = uilabel(app.UIFigure);
            app.minmaxContactC1EditFieldLabel.Tooltip = {'Interval for daily number of contacts, County 1'};
            app.minmaxContactC1EditFieldLabel.Text = 'min/max Contact C1';
            app.minContactC1EditField = uieditfield(app.UIFigure, 'numeric');
            app.minContactC1EditField.Limits = [0 100];
            app.minContactC1EditField.RoundFractionalValues = 'on';
            app.minContactC1EditField.ValueChangedFcn = createCallbackFcn(app, @updateData, true);
            app.minContactC1EditField.Tooltip = {''};
            app.minContactC1EditField.Value = 5;
            app.maxContactC1EditField = uieditfield(app.UIFigure, 'numeric');
            app.maxContactC1EditField.Limits = [0 100];
            app.maxContactC1EditField.RoundFractionalValues = 'on';
            app.maxContactC1EditField.ValueChangedFcn = createCallbackFcn(app, @updateData, true);
            app.maxContactC1EditField.Value = 12;
            app.maxContactC2EditField = uieditfield(app.UIFigure, 'numeric');
            app.maxContactC2EditField.Limits = [0 100];
            app.maxContactC2EditField.RoundFractionalValues = 'on';
            app.maxContactC2EditField.ValueChangedFcn = createCallbackFcn(app, @updateData, true);
            app.maxContactC2EditField.Value = 8;
            app.minmaxContactC2Label = uilabel(app.UIFigure);
            app.minmaxContactC2Label.Tooltip = {'Interval for daily number of contacts, County 2'};
            app.minmaxContactC2Label.Text = 'min/max Contact C2';
            app.minContactC2EditField = uieditfield(app.UIFigure, 'numeric');
            app.minContactC2EditField.Limits = [0 100];
            app.minContactC2EditField.RoundFractionalValues = 'on';
            app.minContactC2EditField.ValueChangedFcn = createCallbackFcn(app, @updateData, true);
            app.minContactC2EditField.Tooltip = {''};
            app.minContactC2EditField.Value = 3;
            app.maxContactC3EditField = uieditfield(app.UIFigure, 'numeric');
            app.maxContactC3EditField.Limits = [0 100];
            app.maxContactC3EditField.RoundFractionalValues = 'on';
            app.maxContactC3EditField.ValueChangedFcn = createCallbackFcn(app, @updateData, true);
            app.maxContactC3EditField.Value = 10;
            app.maxContactC4EditField = uieditfield(app.UIFigure, 'numeric');
            app.maxContactC4EditField.Limits = [0 100];
            app.maxContactC4EditField.RoundFractionalValues = 'on';
            app.maxContactC4EditField.ValueChangedFcn = createCallbackFcn(app, @updateData, true);
            app.maxContactC4EditField.Value = 3;
            app.minmaxContactC3Label = uilabel(app.UIFigure);
            app.minmaxContactC3Label.Tooltip = {'Interval for daily number of contacts, County 3'};
            app.minmaxContactC3Label.Text = 'min/max Contact C3';
            app.minContactC3EditField = uieditfield(app.UIFigure, 'numeric');
            app.minContactC3EditField.Limits = [0 100];
            app.minContactC3EditField.RoundFractionalValues = 'on';
            app.minContactC3EditField.ValueChangedFcn = createCallbackFcn(app, @updateData, true);
            app.minContactC3EditField.Tooltip = {''};
            app.minContactC3EditField.Value = 1;
            app.minmaxContactC4Label = uilabel(app.UIFigure);
            app.minmaxContactC4Label.Tooltip = {'Interval for daily number of contacts, County 4'};
            app.minmaxContactC4Label.Text = 'min/max Contact C4';
            app.minContactC4EditField = uieditfield(app.UIFigure, 'numeric');
            app.minContactC4EditField.Limits = [0 100];
            app.minContactC4EditField.RoundFractionalValues = 'on';
            app.minContactC4EditField.ValueChangedFcn = createCallbackFcn(app, @updateData, true);
            app.minContactC4EditField.Tooltip = {''};
            app.minContactC4EditField.Value = 1;
            app.C1EditFieldLabel = uilabel(app.UIFigure);
            app.C1EditFieldLabel.Text = 'C1';
            app.C11EditField = uieditfield(app.UIFigure, 'numeric');
            app.C11EditField.Limits = [0 1];
            app.C11EditField.RoundFractionalValues = 'on';
            app.C11EditField.ValueDisplayFormat = '%11g';
            app.C11EditField.ValueChangedFcn = createCallbackFcn(app, @updateData, true);
            app.C11EditField.Value = 1;
            app.C12EditField = uieditfield(app.UIFigure, 'numeric');
            app.C12EditField.Limits = [0 1];
            app.C12EditField.RoundFractionalValues = 'on';
            app.C12EditField.ValueDisplayFormat = '%11g';
            app.C12EditField.ValueChangedFcn = createCallbackFcn(app, @updateData, true);
            app.C12EditField.Value = 1;
            app.C13EditField = uieditfield(app.UIFigure, 'numeric');
            app.C13EditField.Limits = [0 1];
            app.C13EditField.RoundFractionalValues = 'on';
            app.C13EditField.ValueDisplayFormat = '%11g';
            app.C13EditField.ValueChangedFcn = createCallbackFcn(app, @updateData, true);
            app.C13EditField.Value = 1;
            app.C14EditField = uieditfield(app.UIFigure, 'numeric');
            app.C14EditField.Limits = [0 1];
            app.C14EditField.RoundFractionalValues = 'on';
            app.C14EditField.ValueDisplayFormat = '%11g';
            app.C14EditField.ValueChangedFcn = createCallbackFcn(app, @updateData, true);
            app.C14EditField.Value = 1;
            app.C22EditField = uieditfield(app.UIFigure, 'numeric');
            app.C22EditField.Limits = [0 1];
            app.C22EditField.RoundFractionalValues = 'on';
            app.C22EditField.ValueDisplayFormat = '%11g';
            app.C22EditField.ValueChangedFcn = createCallbackFcn(app, @updateData, true);
            app.C22EditField.Value = 1;
            app.C23EditField = uieditfield(app.UIFigure, 'numeric');
            app.C23EditField.Limits = [0 1];
            app.C23EditField.RoundFractionalValues = 'on';
            app.C23EditField.ValueDisplayFormat = '%11g';
            app.C23EditField.ValueChangedFcn = createCallbackFcn(app, @updateData, true);
            app.C23EditField.Value = 1;
            app.C24EditField = uieditfield(app.UIFigure, 'numeric');
            app.C24EditField.Limits = [0 1];
            app.C24EditField.RoundFractionalValues = 'on';
            app.C24EditField.ValueDisplayFormat = '%11g';
            app.C24EditField.ValueChangedFcn = createCallbackFcn(app, @updateData, true);
            app.C24EditField.Value = 1;
            app.C2Label = uilabel(app.UIFigure);
            app.C2Label.Text = 'C2';
            app.C21EditField = uieditfield(app.UIFigure, 'numeric');
            app.C21EditField.Limits = [0 1];
            app.C21EditField.RoundFractionalValues = 'on';
            app.C21EditField.ValueDisplayFormat = '%11g';
            app.C21EditField.ValueChangedFcn = createCallbackFcn(app, @updateData, true);
            app.C21EditField.Value = 1;
            app.C32EditField = uieditfield(app.UIFigure, 'numeric');
            app.C32EditField.Limits = [0 1];
            app.C32EditField.RoundFractionalValues = 'on';
            app.C32EditField.ValueDisplayFormat = '%11g';
            app.C32EditField.ValueChangedFcn = createCallbackFcn(app, @updateData, true);
            app.C32EditField.Value = 1;
            app.C33EditField = uieditfield(app.UIFigure, 'numeric');
            app.C33EditField.Limits = [0 1];
            app.C33EditField.RoundFractionalValues = 'on';
            app.C33EditField.ValueDisplayFormat = '%11g';
            app.C33EditField.ValueChangedFcn = createCallbackFcn(app, @updateData, true);
            app.C33EditField.Value = 1;
            app.C34EditField = uieditfield(app.UIFigure, 'numeric');
            app.C34EditField.Limits = [0 1];
            app.C34EditField.RoundFractionalValues = 'on';
            app.C34EditField.ValueDisplayFormat = '%11g';
            app.C34EditField.ValueChangedFcn = createCallbackFcn(app, @updateData, true);
            app.C42EditField = uieditfield(app.UIFigure, 'numeric');
            app.C42EditField.Limits = [0 1];
            app.C42EditField.RoundFractionalValues = 'on';
            app.C42EditField.ValueDisplayFormat = '%11g';
            app.C42EditField.ValueChangedFcn = createCallbackFcn(app, @updateData, true);
            app.C42EditField.Value = 1;
            app.C43EditField = uieditfield(app.UIFigure, 'numeric');
            app.C43EditField.Limits = [0 1];
            app.C43EditField.RoundFractionalValues = 'on';
            app.C43EditField.ValueDisplayFormat = '%11g';
            app.C43EditField.ValueChangedFcn = createCallbackFcn(app, @updateData, true);
            app.C44EditField = uieditfield(app.UIFigure, 'numeric');
            app.C44EditField.Limits = [0 1];
            app.C44EditField.RoundFractionalValues = 'on';
            app.C44EditField.ValueDisplayFormat = '%11g';
            app.C44EditField.ValueChangedFcn = createCallbackFcn(app, @updateData, true);
            app.C44EditField.Value = 1;
            app.C3Label = uilabel(app.UIFigure);
            app.C3Label.Text = 'C3';
            app.C31EditField = uieditfield(app.UIFigure, 'numeric');
            app.C31EditField.Limits = [0 1];
            app.C31EditField.RoundFractionalValues = 'on';
            app.C31EditField.ValueDisplayFormat = '%11g';
            app.C31EditField.ValueChangedFcn = createCallbackFcn(app, @updateData, true);
            app.C31EditField.Value = 1;
            app.C4Label = uilabel(app.UIFigure);
            app.C4Label.Text = 'C4';
            app.C41EditField = uieditfield(app.UIFigure, 'numeric');
            app.C41EditField.Limits = [0 1];
            app.C41EditField.RoundFractionalValues = 'on';
            app.C41EditField.ValueDisplayFormat = '%11g';
            app.C41EditField.ValueChangedFcn = createCallbackFcn(app, @updateData, true);
            app.C41EditField.Value = 1;
            app.C1Label = uilabel(app.UIFigure);
            app.C1Label.Text = 'C1';
            app.C2Label_2 = uilabel(app.UIFigure);
            app.C2Label_2.Text = 'C2';
            app.C3Label_2 = uilabel(app.UIFigure);
            app.C3Label_2.Text = {'C3'; ''};
            app.C4Label_2 = uilabel(app.UIFigure);
            app.C4Label_2.Text = 'C4';
            app.minmaxLayer2Label = uilabel(app.UIFigure);
            app.minmaxLayer2Label.Tooltip = {'Interval for the degree of nodes in Layer 2'};
            app.minmaxLayer2Label.Text = 'min/max Layer 2';
            app.minLayer2EditField = uieditfield(app.UIFigure, 'numeric');
            app.minLayer2EditField.Limits = [0 100];
            app.minLayer2EditField.RoundFractionalValues = 'on';
            app.minLayer2EditField.ValueChangedFcn = createCallbackFcn(app, @updateData, true);
            app.minLayer2EditField.Value = 5;
            app.maxLayer2EditField = uieditfield(app.UIFigure, 'numeric');
            app.maxLayer2EditField.Limits = [0 100];
            app.maxLayer2EditField.RoundFractionalValues = 'on';
            app.maxLayer2EditField.ValueChangedFcn = createCallbackFcn(app, @updateData, true);
            app.maxLayer2EditField.Value = 10;
            app.maxLayer3EditField = uieditfield(app.UIFigure, 'numeric');
            app.maxLayer3EditField.Limits = [0 100];
            app.maxLayer3EditField.RoundFractionalValues = 'on';
            app.maxLayer3EditField.ValueChangedFcn = createCallbackFcn(app, @updateData, true);
            app.maxLayer3EditField.Value = 15;
            app.minmaxLayer3Label = uilabel(app.UIFigure);
            app.minmaxLayer3Label.Tooltip = {'Interval for the degree of nodes in Layer 3'};
            app.minmaxLayer3Label.Text = 'min/max Layer 3';
            app.minLayer3EditField = uieditfield(app.UIFigure, 'numeric');
            app.minLayer3EditField.Limits = [0 100];
            app.minLayer3EditField.RoundFractionalValues = 'on';
            app.minLayer3EditField.ValueChangedFcn = createCallbackFcn(app, @updateData, true);
            app.minLayer3EditField.Value = 1;
            app.OisolatedLabel = uilabel(app.UIFigure);
            app.OisolatedLabel.Text = '%O isolated';
            app.IsolatedSlider = uislider(app.UIFigure);
            app.IsolatedSlider.Limits = [0 1];
            app.IsolatedSlider.Tooltip = {'% diagnosed nodes that isolate'};
            app.IsolatedSlider.Value = 0.1;
            app.p1EditFieldLabel = uilabel(app.UIFigure);
            app.p1EditFieldLabel.Tooltip = {'Probability of infection'};
            app.p1EditFieldLabel.Text = 'p1';
            app.p1EditField = uieditfield(app.UIFigure, 'numeric');
            app.p1EditField.Limits = [0 1];
            app.p1EditField.ValueDisplayFormat = '%1.3g';
            app.p1EditField.Tooltip = {''};
            app.p1EditField.Value = 0.21;
            app.p2Label = uilabel(app.UIFigure);
            app.p2Label.Tooltip = {'Probability if nodes keep social distance'};
            app.p2Label.Text = 'p2';
            app.p2EditField = uieditfield(app.UIFigure, 'numeric');
            app.p2EditField.Limits = [0 1];
            app.p2EditField.ValueDisplayFormat = '%1.3g';
            app.p2EditField.Tooltip = {''};
            app.p2EditField.Value = 0.13;
            app.p3Label = uilabel(app.UIFigure);
            app.p3Label.Tooltip = {'Probability if nodes wear amask'};
            app.p3Label.Text = 'p3';
            app.p3EditField = uieditfield(app.UIFigure, 'numeric');
            app.p3EditField.Limits = [0 1];
            app.p3EditField.ValueDisplayFormat = '%1.3g';
            app.p3EditField.Tooltip = {''};
            app.p3EditField.Value = 0.07;
            app.p4Label = uilabel(app.UIFigure);
            app.p4Label.Tooltip = {'Probability if nodes keep social distance and wear a mask'};
            app.p4Label.Text = 'p4';
            app.p4EditField = uieditfield(app.UIFigure, 'numeric');
            app.p4EditField.Limits = [0 1];
            app.p4EditField.ValueDisplayFormat = '%1.3g';
            app.p4EditField.Tooltip = {''};
            app.p4EditField.Value = 0.04;
            app.MaskLabel = uilabel(app.UIFigure);
            app.MaskLabel.Text = 'Mask';
            app.MaskSlider = uislider(app.UIFigure);
            app.MaskSlider.Limits = [0 1];
            app.MaskSlider.Tooltip = {'% people wearing a mask'};
            app.MaskSlider.Value = 0.3;
            app.DistancingSliderLabel = uilabel(app.UIFigure);
            app.DistancingSliderLabel.Text = 'Distancing';
            app.DistancingSlider = uislider(app.UIFigure);
            app.DistancingSlider.Limits = [0 1];
            app.DistancingSlider.Tooltip = {'% people that respects social distancing'};
            app.DistancingSlider.Value = 0.5;
            app.C1EditFieldLabel_2 = uilabel(app.UIFigure);
            app.C1EditFieldLabel_2.Text = 'C1';
            app.C1HouseEditField = uieditfield(app.UIFigure, 'numeric');
            app.C1HouseEditField.Limits = [1 500];
            app.C1HouseEditField.RoundFractionalValues = 'on';
            app.C1HouseEditField.ValueChangedFcn = createCallbackFcn(app, @updateData, true);
            app.C1HouseEditField.Tooltip = {'Number of families, County 1'};
            app.C1HouseEditField.Value = 200;
            app.C2EditFieldLabel = uilabel(app.UIFigure);
            app.C2EditFieldLabel.Text = 'C2';
            app.C2HouseEditField = uieditfield(app.UIFigure, 'numeric');
            app.C2HouseEditField.Limits = [1 500];
            app.C2HouseEditField.RoundFractionalValues = 'on';
            app.C2HouseEditField.ValueChangedFcn = createCallbackFcn(app, @updateData, true);
            app.C2HouseEditField.Tooltip = {'Number of families, County 2'};
            app.C2HouseEditField.Value = 300;
            app.C3EditFieldLabel = uilabel(app.UIFigure);
            app.C3EditFieldLabel.Text = 'C3';
            app.C3HouseEditField = uieditfield(app.UIFigure, 'numeric');
            app.C3HouseEditField.Limits = [1 500];
            app.C3HouseEditField.RoundFractionalValues = 'on';
            app.C3HouseEditField.ValueChangedFcn = createCallbackFcn(app, @updateData, true);
            app.C3HouseEditField.Tooltip = {'Number of families, County 3'};
            app.C3HouseEditField.Value = 170;
            app.C4EditFieldLabel = uilabel(app.UIFigure);
            app.C4EditFieldLabel.Text = 'C4';
            app.C4HouseEditField = uieditfield(app.UIFigure, 'numeric');
            app.C4HouseEditField.Limits = [1 500];
            app.C4HouseEditField.RoundFractionalValues = 'on';
            app.C4HouseEditField.ValueChangedFcn = createCallbackFcn(app, @updateData, true);
            app.C4HouseEditField.Tooltip = {'Number of families, County 4'};
            app.C4HouseEditField.Value = 210;
            app.C1PopEditField = uieditfield(app.UIFigure, 'numeric');
            app.C1PopEditField.Limits = [1 5000];
            app.C1PopEditField.RoundFractionalValues = 'on';
            app.C1PopEditField.ValueChangedFcn = createCallbackFcn(app, @updateData, true);
            app.C1PopEditField.Tooltip = {'Population, County 1'};
            app.C1PopEditField.Value = 900;
            app.C2PopEditField = uieditfield(app.UIFigure, 'numeric');
            app.C2PopEditField.Limits = [1 5000];
            app.C2PopEditField.RoundFractionalValues = 'on';
            app.C2PopEditField.ValueChangedFcn = createCallbackFcn(app, @updateData, true);
            app.C2PopEditField.Tooltip = {'Population, County 2'};
            app.C2PopEditField.Value = 1200;
            app.C3PopEditField = uieditfield(app.UIFigure, 'numeric');
            app.C3PopEditField.Limits = [1 5000];
            app.C3PopEditField.RoundFractionalValues = 'on';
            app.C3PopEditField.ValueChangedFcn = createCallbackFcn(app, @updateData, true);
            app.C3PopEditField.Tooltip = {'Population, County 3'};
            app.C3PopEditField.Value = 650;
            app.C4PopEditField = uieditfield(app.UIFigure, 'numeric');
            app.C4PopEditField.Limits = [1 5000];
            app.C4PopEditField.RoundFractionalValues = 'on';
            app.C4PopEditField.ValueChangedFcn = createCallbackFcn(app, @updateData, true);
            app.C4PopEditField.Tooltip = {'Population, County 4'};
            app.C4PopEditField.Value = 1200;
            app.C1A1PopEditField = uieditfield(app.UIFigure, 'numeric');
            app.C1A1PopEditField.Limits = [0 1];
            app.C1A1PopEditField.ValueChangedFcn = createCallbackFcn(app, @updateData, true);
            app.C1A1PopEditField.Tooltip = {'% age-group 1, County 1'};
            app.C1A1PopEditField.Value = 0.3;
            app.C1A2PopEditField = uieditfield(app.UIFigure, 'numeric');
            app.C1A2PopEditField.Limits = [0 1];
            app.C1A2PopEditField.ValueChangedFcn = createCallbackFcn(app, @updateData, true);
            app.C1A2PopEditField.Tooltip = {'% age-group 2, County 1'};
            app.C1A2PopEditField.Value = 0.5;
            app.C2A1PopEditField = uieditfield(app.UIFigure, 'numeric');
            app.C2A1PopEditField.Limits = [0 1];
            app.C2A1PopEditField.ValueChangedFcn = createCallbackFcn(app, @updateData, true);
            app.C2A1PopEditField.Tooltip = {'% age-group 1, County 2'};
            app.C2A1PopEditField.Value = 0.3;
            app.C2A2PopEditField = uieditfield(app.UIFigure, 'numeric');
            app.C2A2PopEditField.Limits = [0 1];
            app.C2A2PopEditField.ValueChangedFcn = createCallbackFcn(app, @updateData, true);
            app.C2A2PopEditField.Tooltip = {'% age-group 2, County 2'};
            app.C2A2PopEditField.Value = 0.5;
            app.C3A1PopEditField = uieditfield(app.UIFigure, 'numeric');
            app.C3A1PopEditField.Limits = [0 1];
            app.C3A1PopEditField.ValueChangedFcn = createCallbackFcn(app, @updateData, true);
            app.C3A1PopEditField.Tooltip = {'% age-group 1, County 3'};
            app.C3A1PopEditField.Value = 0.3;
            app.C3A2PopEditField = uieditfield(app.UIFigure, 'numeric');
            app.C3A2PopEditField.Limits = [0 1];
            app.C3A2PopEditField.ValueChangedFcn = createCallbackFcn(app, @updateData, true);
            app.C3A2PopEditField.Tooltip = {'% age-group 2, County 3'};
            app.C3A2PopEditField.Value = 0.5;
            app.C4A1PopEditField = uieditfield(app.UIFigure, 'numeric');
            app.C4A1PopEditField.Limits = [0 1];
            app.C4A1PopEditField.ValueChangedFcn = createCallbackFcn(app, @updateData, true);
            app.C4A1PopEditField.Tooltip = {'% age-group 1, County 4'};
            app.C4A1PopEditField.Value = 0.3;
            app.C4A2PopEditField = uieditfield(app.UIFigure, 'numeric');
            app.C4A2PopEditField.Limits = [0 1];
            app.C4A2PopEditField.ValueChangedFcn = createCallbackFcn(app, @updateData, true);
            app.C4A2PopEditField.Tooltip = {'% age-group 2, County 4'};
            app.C4A2PopEditField.Value = 0.5;
            app.PopLabel = uilabel(app.UIFigure);
            app.PopLabel.Text = 'Pop';
            app.NFLabel = uilabel(app.UIFigure);
            app.NFLabel.Text = 'NF';
            app.AG1Label = uilabel(app.UIFigure);
            app.AG1Label.Text = 'AG1';
            app.AG2Label = uilabel(app.UIFigure);
            app.AG2Label.Text = 'AG2';
            app.PlotresultsforDropDownLabel = uilabel(app.UIFigure);
            app.PlotresultsforDropDownLabel.Text = 'Plot results for';
            app.PlotresultsforDropDown = uidropdown(app.UIFigure);
            app.PlotresultsforDropDown.Items = {'Country', 'C1', 'C2', 'C3', 'C4'};
            app.PlotresultsforDropDown.ValueChangedFcn = createCallbackFcn(app, @plotData, true);
            app.PlotresultsforDropDown.Value = 'Country';
            app.SCheckBox = uicheckbox(app.UIFigure);
            app.SCheckBox.ValueChangedFcn = createCallbackFcn(app, @plotData, true);
            app.SCheckBox.Text = 'S';
            app.ECheckBox = uicheckbox(app.UIFigure);
            app.ECheckBox.ValueChangedFcn = createCallbackFcn(app, @plotData, true);
            app.ECheckBox.Text = 'E';
            app.OCheckBox = uicheckbox(app.UIFigure);
            app.OCheckBox.ValueChangedFcn = createCallbackFcn(app, @plotData, true);
            app.OCheckBox.Text = 'O';
            app.OCheckBox.Value = true;
            app.UCheckBox = uicheckbox(app.UIFigure);
            app.UCheckBox.ValueChangedFcn = createCallbackFcn(app, @plotData, true);
            app.UCheckBox.Text = 'U';
            app.HCheckBox = uicheckbox(app.UIFigure);
            app.HCheckBox.ValueChangedFcn = createCallbackFcn(app, @plotData, true);
            app.HCheckBox.Text = 'H';
            app.HCheckBox.Value = true;
            app.RCheckBox = uicheckbox(app.UIFigure);
            app.RCheckBox.ValueChangedFcn = createCallbackFcn(app, @plotData, true);
            app.RCheckBox.Text = 'R';
            app.DCheckBox = uicheckbox(app.UIFigure);
            app.DCheckBox.ValueChangedFcn = createCallbackFcn(app, @plotData, true);
            app.DCheckBox.Text = 'D';
            app.O1CheckBox = uicheckbox(app.UIFigure);
            app.O1CheckBox.ValueChangedFcn = createCallbackFcn(app, @plotData, true);
            app.O1CheckBox.Text = 'O1';
            app.O2CheckBox = uicheckbox(app.UIFigure);
            app.O2CheckBox.ValueChangedFcn = createCallbackFcn(app, @plotData, true);
            app.O2CheckBox.Text = 'O2';
            app.O3CheckBox = uicheckbox(app.UIFigure);
            app.O3CheckBox.ValueChangedFcn = createCallbackFcn(app, @plotData, true);
            app.O3CheckBox.Text = 'O3';
            app.H1CheckBox = uicheckbox(app.UIFigure);
            app.H1CheckBox.ValueChangedFcn = createCallbackFcn(app, @plotData, true);
            app.H1CheckBox.Text = 'H1';
            app.H2CheckBox = uicheckbox(app.UIFigure);
            app.H2CheckBox.ValueChangedFcn = createCallbackFcn(app, @plotData, true);
            app.H2CheckBox.Text = 'H2';
            app.H3CheckBox = uicheckbox(app.UIFigure);
            app.H3CheckBox.ValueChangedFcn = createCallbackFcn(app, @plotData, true);
            app.H3CheckBox.Text = 'H3';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.totalcasesCheckBox = uicheckbox(app.UIFigure);
            app.totalcasesCheckBox.ValueChangedFcn = createCallbackFcn(app, @plotData, true);
            app.totalcasesCheckBox.Text = 'total cases';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'epimec.jpg';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.ShowpreviousresultsButton = uibutton(app.UIFigure, 'state');
            app.ShowpreviousresultsButton.ValueChangedFcn = createCallbackFcn(app, @plotData, true);
            app.ShowpreviousresultsButton.Tooltip = {'Last simulation is shown in dashed lines '};
            app.ShowpreviousresultsButton.Text = 'Show previous results';
            app.FamilyinfoLabel = uilabel(app.UIFigure);
            app.FamilyinfoLabel.Text = 'Family info';
            app.ConnectivityLabel = uilabel(app.UIFigure);
            app.ConnectivityLabel.Tooltip = {'1 if there is connectivity between counties'};
            app.ConnectivityLabel.Text = 'Connectivity';
            app.CopyrightC2021EpiMECLabel = uilabel(app.UIFigure);
            app.CopyrightC2021EpiMECLabel.Text = 'Copyright (C) 2021, EpiMEC';
            app.C1A3PopEditField = uieditfield(app.UIFigure, 'numeric');
            app.C1A3PopEditField.Limits = [0 1];
            app.C1A3PopEditField.Editable = 'off';
            app.C1A3PopEditField.Tooltip = {'% age-group 3, County 1'};
            app.C1A3PopEditField.Value = 0.5;
            app.C2A3PopEditField = uieditfield(app.UIFigure, 'numeric');
            app.C2A3PopEditField.Limits = [0 1];
            app.C2A3PopEditField.Editable = 'off';
            app.C2A3PopEditField.Tooltip = {'% age-group 3, County 2'};
            app.C2A3PopEditField.Value = 0.5;
            app.C3A3PopEditField = uieditfield(app.UIFigure, 'numeric');
            app.C3A3PopEditField.Limits = [0 1];
            app.C3A3PopEditField.Editable = 'off';
            app.C3A3PopEditField.Tooltip = {'% age-group 3, County 3'};
            app.C3A3PopEditField.Value = 0.5;
            app.C4A3PopEditField = uieditfield(app.UIFigure, 'numeric');
            app.C4A3PopEditField.Limits = [0 1];
            app.C4A3PopEditField.Editable = 'off';
            app.C4A3PopEditField.Tooltip = {'% age-group 3, County 4'};
            app.C4A3PopEditField.Value = 0.5;
            app.AG3Label = uilabel(app.UIFigure);
            app.AG3Label.Text = 'AG3';
            app.LoadtransitionsbetweenstatesButton = uibutton(app.UIFigure, 'push');
            app.LoadtransitionsbetweenstatesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadtransitionsbetweenstatesButtonPushed, true);
            app.LoadtransitionsbetweenstatesButton.Tooltip = {'Default: ''prob.xls'''};
            app.LoadtransitionsbetweenstatesButton.Text = 'Load transitions between states';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, '% Cases')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 100];
            app.UIAxes.YLim = [0 100];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = covidCR_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end